classdef drawroiGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Nowonlyworksforatmost3channelsLabel  matlab.ui.control.Label
        SelectROIButton          matlab.ui.control.Button
        RotateAngleSpinner       matlab.ui.control.Spinner
        RotateAngleSpinnerLabel  matlab.ui.control.Label
        ImageAxes3               matlab.ui.control.UIAxes
        ImageAxes2               matlab.ui.control.UIAxes
        ImageAxes1               matlab.ui.control.UIAxes
    end


    properties (Access = public)
        im1 % Raw image
        im2
        im3
        rotateAngle
        roiPosition
        roi1
        roi2
        roi3
    end

    methods (Access = private)

        function updateroi1(app)
            %             disp('update1');
            app.roiPosition = app.roi1.Position;
            app.updateroiall();
        end

        function updateroi2(app)
            %             disp('update2');
            app.roiPosition = app.roi2.Position;
            app.updateroiall();
        end

        function updateroi3(app)
            %             disp('update3');
            app.roiPosition = app.roi3.Position;
            app.updateroiall();
        end

        function updateroiall(app)
            %             disp('updateall');
            app.roi1.Position = app.roiPosition;
            app.roi2.Position = app.roiPosition;
            app.roi3.Position = app.roiPosition;
        end

        function resetroi(app)
            if ~isempty(app.roi1)
                delete(app.roi1);
                delete(app.roi2);
                delete(app.roi3);
            end
        end

    end

    methods (Access = public)

        function [rotateAngle, roiPosition, cropSize] = getroi(app)
            roiPosition = app.roiPosition;
            rotateAngle = app.rotateAngle;
            cropSize = size(imcrop(app.im1, roiPosition));
            delete(app);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, filename, postInfo, contrastMethod)
            
            nChannel = length(postInfo.exportedChannelNo);
            f = Nd2Reader(filename);
            frameStack = postInfo.frames{1};
            switch contrastMethod
                case 0
                    lowhigh = [0 1];
                    lowhigh = repmat(lowhigh, [nChannel 1]);
                case 1
                    lowhigh = postInfo.autoContrastPara{1};
                case 2
                    lowhigh = postInfo.manualContrastPara{1};
                otherwise
                    error('Variable contrastMethod are unavailable.');
            end
            
            if nChannel == 1
                app.im1 = imadjust(f.getimage(frameStack(1)), lowhigh);
                app.im2 = imadjust(f.getimage(frameStack(round(end/2))), lowhigh);
                app.im3 = imadjust(f.getimage(frameStack(end)), lowhigh);
            else
                im = f.getimage(frameStack(1));
                for iC = 1:nChannel
                    eval(['app.im' num2str(iC) '=imadjust(im(:,:,' num2str(iC) '), lowhigh(' num2str(iC) ',:));']);
                    % app.im1 = imadjust(im(:,:,1), lowhigh(1,:));
                    % app.im2 = imadjust(im(:,:,2), lowhigh(2,:));
                    % app.im3 = imadjust(f.getimage(frameStack(end)), lowhigh);
                end
            end
            f.close();

            % default value
            app.rotateAngle = 0;
            app.roiPosition = [1 1 [size(app.im1, [2 1])]];
            axis(app.ImageAxes1, 'image');
            axis(app.ImageAxes2, 'image');
            axis(app.ImageAxes3, 'image');
            disableDefaultInteractivity(app.ImageAxes1);  % Fix bug of 4-arror pointer outside of roi. Fix at 2022b according to Mathwork.
            disableDefaultInteractivity(app.ImageAxes2);
            disableDefaultInteractivity(app.ImageAxes3);

            imshow(app.im1, 'Parent', app.ImageAxes1);
            imshow(app.im2, 'Parent', app.ImageAxes2);
            imshow(app.im3, 'Parent', app.ImageAxes3);
            uiwait(app.UIFigure);
        end

        % Value changed function: RotateAngleSpinner
        function RotateAngleSpinnerValueChanged(app, event)
            app.rotateAngle = app.RotateAngleSpinner.Value;
            rotatedImage1 = imrotate(app.im1, app.rotateAngle,'bicubic','crop');
            rotatedImage2 = imrotate(app.im2, app.rotateAngle,'bicubic','crop');
            rotatedImage3 = imrotate(app.im3, app.rotateAngle,'bicubic','crop');
            app.resetroi();
            imshow(rotatedImage1, 'Parent', app.ImageAxes1);
            imshow(rotatedImage2, 'Parent', app.ImageAxes2);
            imshow(rotatedImage3, 'Parent', app.ImageAxes3);
        end

        % Button pushed function: SelectROIButton
        function SelectROIButtonPushed(app, event)
            app.resetroi();
            app.roi1 = drawrectangle(app.ImageAxes1);
            app.roiPosition = app.roi1.Position;
            app.roi2 = drawrectangle(app.ImageAxes2, 'Position', app.roi1.Position);
            app.roi3 = drawrectangle(app.ImageAxes3, 'Position', app.roi1.Position);
            addlistener(app.roi1, 'ROIMoved', @(~,~) app.updateroi1); % check with events(roi)
            addlistener(app.roi2, 'ROIMoved', @(~,~) app.updateroi2);
            addlistener(app.roi3, 'ROIMoved', @(~,~) app.updateroi3);
            uiwait(app.UIFigure);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            uiresume(app.UIFigure);
            %             delete(app.roi1);
            %             delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ImageAxes1 = uiaxes(app.UIFigure);
            app.ImageAxes1.XTick = [];
            app.ImageAxes1.XTickLabel = {'[ ]'};
            app.ImageAxes1.YTick = [];
            app.ImageAxes2 = uiaxes(app.UIFigure);
            app.ImageAxes2.XTick = [];
            app.ImageAxes2.XTickLabel = {'[ ]'};
            app.ImageAxes2.YTick = [];
            app.ImageAxes3 = uiaxes(app.UIFigure);
            app.ImageAxes3.XTick = [];
            app.ImageAxes3.XTickLabel = {'[ ]'};
            app.ImageAxes3.YTick = [];
            app.RotateAngleSpinnerLabel = uilabel(app.UIFigure);
            app.RotateAngleSpinnerLabel.Text = 'Rotate Angle';
            app.RotateAngleSpinner = uispinner(app.UIFigure);
            app.RotateAngleSpinner.ValueChangedFcn = createCallbackFcn(app, @RotateAngleSpinnerValueChanged, true);
            app.SelectROIButton = uibutton(app.UIFigure, 'push');
            app.SelectROIButton.ButtonPushedFcn = createCallbackFcn(app, @SelectROIButtonPushed, true);
            app.SelectROIButton.Text = 'Select ROI';
            app.Nowonlyworksforatmost3channelsLabel = uilabel(app.UIFigure);
            app.Nowonlyworksforatmost3channelsLabel.Text = 'Now only works for at most 3 channels.';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = drawroiGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end