classdef v1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UploadFilenameButton            matlab.ui.control.Button
        FilenameEditFieldLabel          matlab.ui.control.Label
        FilenameEditField               matlab.ui.control.EditField
        SavepathEditFieldLabel          matlab.ui.control.Label
        SavepathEditField               matlab.ui.control.EditField
        AutoContrastCheckBox            matlab.ui.control.CheckBox
        ConcatenateCheckBox             matlab.ui.control.CheckBox
        ScalebarCheckBox                matlab.ui.control.CheckBox
        WithTextCheckBox                matlab.ui.control.CheckBox
        TimeStampCheckBox               matlab.ui.control.CheckBox
        RunButton                       matlab.ui.control.Button
        ObjectivesEditFieldLabel        matlab.ui.control.Label
        ObjectivesEditField             matlab.ui.control.NumericEditField
        ChannelNumberEditFieldLabel     matlab.ui.control.Label
        ChannelNumberEditField          matlab.ui.control.NumericEditField
        CountEveryFramesEditFieldLabel  matlab.ui.control.Label
        CountEveryFramesEditField       matlab.ui.control.NumericEditField
        SelectedChannelListBoxLabel     matlab.ui.control.Label
        SelectedChannelListBox          matlab.ui.control.ListBox
        PreviewButton                   matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UploadFilenameButton
        function UploadFilenameButtonPushed(app, event)
            filterspec = {'*.nd2;*.tif'};
            [f, p] = uigetfile(filterspec);
            fname = [p f];
            app.FilenameEditField.Value = fname;
            app.SavepathEditField.Value = p;
        end

        % Value changed function: ChannelNumberEditField
        function ChannelNumberEditFieldValueChanged(app, event)
            app.SelectedChannelListBox.Items = {};
            value = app.ChannelNumberEditField.Value;
            for i = 1:value
                app.SelectedChannelListBox.Items{i} = ['Channel ' num2str(i)];
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UploadFilenameButton = uibutton(app.UIFigure, 'push');
            app.UploadFilenameButton.ButtonPushedFcn = createCallbackFcn(app, @UploadFilenameButtonPushed, true);
            app.UploadFilenameButton.Text = 'Upload Filename';
            app.FilenameEditFieldLabel = uilabel(app.UIFigure);
            app.FilenameEditFieldLabel.Text = 'Filename';
            app.FilenameEditField = uieditfield(app.UIFigure, 'text');
            app.SavepathEditFieldLabel = uilabel(app.UIFigure);
            app.SavepathEditFieldLabel.Text = 'Savepath';
            app.SavepathEditField = uieditfield(app.UIFigure, 'text');
            app.AutoContrastCheckBox = uicheckbox(app.UIFigure);
            app.AutoContrastCheckBox.Text = 'Auto Contrast';
            app.AutoContrastCheckBox.Value = true;
            app.ConcatenateCheckBox = uicheckbox(app.UIFigure);
            app.ConcatenateCheckBox.Text = 'Concatenate';
            app.ConcatenateCheckBox.Value = true;
            app.ScalebarCheckBox = uicheckbox(app.UIFigure);
            app.ScalebarCheckBox.Text = 'Scalebar';
            app.ScalebarCheckBox.Value = true;
            app.WithTextCheckBox = uicheckbox(app.UIFigure);
            app.WithTextCheckBox.Text = 'With Text';
            app.TimeStampCheckBox = uicheckbox(app.UIFigure);
            app.TimeStampCheckBox.Text = 'Time Stamp';
            app.TimeStampCheckBox.Value = true;
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.Text = 'Run';
            app.ObjectivesEditFieldLabel = uilabel(app.UIFigure);
            app.ObjectivesEditFieldLabel.Text = 'Objectives';
            app.ObjectivesEditField = uieditfield(app.UIFigure, 'numeric');
            app.ObjectivesEditField.Value = 4;
            app.ChannelNumberEditFieldLabel = uilabel(app.UIFigure);
            app.ChannelNumberEditFieldLabel.Text = 'Channel Number';
            app.ChannelNumberEditField = uieditfield(app.UIFigure, 'numeric');
            app.ChannelNumberEditField.ValueChangedFcn = createCallbackFcn(app, @ChannelNumberEditFieldValueChanged, true);
            app.ChannelNumberEditField.Value = 1;
            app.CountEveryFramesEditFieldLabel = uilabel(app.UIFigure);
            app.CountEveryFramesEditFieldLabel.Text = 'Count Every Frames';
            app.CountEveryFramesEditField = uieditfield(app.UIFigure, 'numeric');
            app.CountEveryFramesEditField.Value = 2;
            app.SelectedChannelListBoxLabel = uilabel(app.UIFigure);
            app.SelectedChannelListBoxLabel.Text = 'Selected Channel';
            app.SelectedChannelListBox = uilistbox(app.UIFigure);
            app.SelectedChannelListBox.Items = {'Channel 1'};
            app.SelectedChannelListBox.Multiselect = 'on';
            app.SelectedChannelListBox.Value = {};
            app.PreviewButton = uibutton(app.UIFigure, 'push');
            app.PreviewButton.Text = 'Preview';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = v1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end